classdef app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainTab                    matlab.ui.container.TabGroup
        TampilanHistogramTab       matlab.ui.container.Tab
        TampilkanHistogramButton   matlab.ui.control.Button
        JudulCitraLabel            matlab.ui.control.Label
        PilihFileCitraButton       matlab.ui.control.Button
        ModeCitraButtonGroup       matlab.ui.container.ButtonGroup
        BerwarnaButton             matlab.ui.control.RadioButton
        GrayscaleButton            matlab.ui.control.RadioButton
        PerbaikanKualitasTab       matlab.ui.container.Tab
        PerataanHistogramTab       matlab.ui.container.Tab
        HistogramSpecificationTab  matlab.ui.container.Tab
    end

    
    methods (Access = public)
        
        % Returns frequency array of intensity values in range [0, 256)
        function res = GetFrequency(~, image, grayscale)
            if grayscale
                res = zeros([1, 256]);
                for i = 1:size(image, 1)
                    for j = 1:size(image, 2)
                        pixelValue = image(i, j) + 1; % +1 because arrays are one-indexed
                        res(pixelValue) = res(pixelValue) + 1;
                    end
                end
            else % RGB
                res = zeros([3, 256]);
                for c = 1:size(image, 3)
                    for i = 1:size(image, 1)
                        for j = 1:size(image, 2)
                            pixelValue = image(i, j, c) + 1; % +1 because arrays are one-indexed
                            res(c, pixelValue) = res(c, pixelValue) + 1;
                        end
                    end
                end
            end
        end
        
        function ShowHistogram(~, freq, grayscale)
            if grayscale
                bar(0:1:255, freq);
            else
                figure
                title("Histogram Red")
                bar(0:1:255, freq(1, :),"red");
                figure
                title("Histogram Blue")
                bar(0:1:255, freq(2, :), "blue");
                figure
                title("Histogram Green")
                bar(0:1:255, freq(3, :), "green");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton
        function PilihFileCitraButtonPushed(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png';'*.jpg;';'*.jpeg'});
            if file ~= 0
                comp.JudulCitraLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: TampilkanHistogramButton
        function TampilkanHistogramButtonPushed(comp, event)
            try
                image = imread(comp.JudulCitraLabel.Text);
                image = rescale(image, 0, 255); % enforcing intensity range
                freq = comp.GetFrequency(image, comp.GrayscaleButton.Value);
                comp.ShowHistogram(freq, comp.GrayscaleButton.Value)
            catch e
                msgbox("File citra tidak ditemukan.", "Error", "error");
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 657 461];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.MainTab = uitabgroup(comp);
            comp.TampilanHistogramTab = uitab(comp.MainTab);
            comp.TampilanHistogramTab.Title = 'Tampilan Histogram';
            comp.ModeCitraButtonGroup = uibuttongroup(comp.TampilanHistogramTab);
            comp.ModeCitraButtonGroup.Title = 'Mode Citra';
            comp.GrayscaleButton = uiradiobutton(comp.ModeCitraButtonGroup);
            comp.GrayscaleButton.Text = 'Grayscale';
            comp.GrayscaleButton.Value = true;
            comp.BerwarnaButton = uiradiobutton(comp.ModeCitraButtonGroup);
            comp.BerwarnaButton.Text = 'Berwarna';
            comp.PilihFileCitraButton = uibutton(comp.TampilanHistogramTab, 'push');
            comp.PilihFileCitraButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PilihFileCitraButtonPushed, true);
            comp.PilihFileCitraButton.Text = 'Pilih File Citra';
            comp.JudulCitraLabel = uilabel(comp.TampilanHistogramTab);
            comp.JudulCitraLabel.Text = 'Judul Citra';
            comp.TampilkanHistogramButton = uibutton(comp.TampilanHistogramTab, 'push');
            comp.TampilkanHistogramButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TampilkanHistogramButtonPushed, true);
            comp.TampilkanHistogramButton.Text = 'Tampilkan Histogram';
            comp.PerbaikanKualitasTab = uitab(comp.MainTab);
            comp.PerbaikanKualitasTab.Title = 'Perbaikan Kualitas';
            comp.PerataanHistogramTab = uitab(comp.MainTab);
            comp.PerataanHistogramTab.Title = 'Perataan Histogram';
            comp.HistogramSpecificationTab = uitab(comp.MainTab);
            comp.HistogramSpecificationTab.Title = 'Histogram Specification';
        end
    end
end